classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        MagnetizationinputdataTab  matlab.ui.container.Tab
        AnhystereticmagnetizationfittingTab  matlab.ui.container.Tab
        AnhystereticmagnetizationfittingTabGridLayout  matlab.ui.container.GridLayout
        GridLayoutAxes             matlab.ui.container.GridLayout
        GridLayoutLogHdMdH         matlab.ui.container.GridLayout
        logCheckBoxHdMdH           matlab.ui.control.CheckBox
        GridLayoutLogdMdH          matlab.ui.container.GridLayout
        logCheckBoxdMdH            matlab.ui.control.CheckBox
        GridLayoutLogM             matlab.ui.container.GridLayout
        logCheckBoxM               matlab.ui.control.CheckBox
        AxesHdMdH                  matlab.ui.control.UIAxes
        AxesdMdH                   matlab.ui.control.UIAxes
        AxesM                      matlab.ui.control.UIAxes
        GridLayoutNumbers          matlab.ui.container.GridLayout
        GridLayoutButtons          matlab.ui.container.GridLayout
        CalculatePlotButton        matlab.ui.control.Button
        FitButton                  matlab.ui.control.Button
        TableComponents            matlab.ui.control.Table
        CalculatedparametersLabel  matlab.ui.control.Label
        TableParameters            matlab.ui.control.Table
        ComponentsLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        H
        M
        dMdH
        HdMdH
        Hhat
        Mhat
        dMdHhat
        HdMdHhat
        Hcr
        mcr
        a
        alphaMs
        Ms
        alpha
        dimensionless_alphaMs
        Hk
    end
    
    methods (Access = private)
        
        function results = plot(app)
            cla(app.AxesM,'reset');
            cla(app.AxesdMdH,'reset');
            cla(app.AxesHdMdH,'reset');

            plotter = Plotter(app.H, app.M, app.dMdH, app.HdMdH, app.Hhat, app.Mhat, app.dMdHhat, app.HdMdHhat, app.Hcr);
            if(app.logCheckBoxM.Value == 0)
                plotter.plot_M(app.AxesM);
            else
                plotter.plot_M_log(app.AxesM);
            end

            if(app.logCheckBoxdMdH.Value == 0)
                plotter.plot_dMdH(app.AxesdMdH);
            else
                plotter.plot_dMdH_log(app.AxesdMdH);
            end

            if(app.logCheckBoxHdMdH.Value == 0)
                plotter.plot_HdMdH(app.AxesHdMdH);
            else
                plotter.plot_HdMdH_log(app.AxesHdMdH);
            end

            t = table([app.a(1), app.a(2); app.alphaMs(1), app.alphaMs(2); app.Ms(1), app.Ms(2); app.alpha(1), app.alpha(2); app.dimensionless_alphaMs(1), app.dimensionless_alphaMs(2); app.Hk(1), app.Hk(2)], 'RowNames', {'a'; 'αMs'; 'Ms'; 'α'; 'αMs/(3a)'; 'Hk'});
            app.TableComponents.Data = t{:,:};
            app.TableComponents.RowName = t.Properties.RowNames;

            app.TableParameters.Data(1) = app.Hcr(1);
            app.TableParameters.Data(3) = app.Hcr(2);
            app.TableParameters.Data(2) = app.mcr(1);
            app.TableParameters.Data(4) = app.mcr(2);
            app.TableParameters.Data(5) = (app.Hcr(1) + app.Hcr(2))/2;
        end

        function results = calculate_parameters(app)
            N = 100;

            utils = Utils();

            [app.H, app.M] = Parser('C:\ubuntu\mag-analyst\data/MvsH - (JNEX-900, AIP advances).csv').get_data_csv;

            app.dMdH = transpose(gradient(app.M(:)) ./ gradient(app.H(:)));

            app.HdMdH = app.H.*app.dMdH;

            [HTip, MTip] = utils.find_tip(app.H, app.M);

            magnetic_parameters = MagneticParameters(app.H, app.M, app.Hcr, app.mcr);
            app.a = magnetic_parameters.get_a();
            app.alphaMs = magnetic_parameters.get_alphaMs(app.a);
            app.Ms = magnetic_parameters.get_Ms(app.a, app.alphaMs);
            app.alpha = magnetic_parameters.get_alpha(app.alphaMs, app.Ms);
            app.dimensionless_alphaMs = magnetic_parameters.get_dimensionless_alphaMs(app.alphaMs, app.a);
            app.Hk = magnetic_parameters.get_Hk(app.alphaMs, app.a);

            app.Hhat = logspace(log10(app.H(2)),log10(HTip),N);
            app.Mhat = get_Mhat(app.Hhat, app.a, app.alphaMs, app.Ms);
            app.dMdHhat = get_dMdHhat(app.Hhat, app.alpha, app.Ms, app.a, app.alphaMs);
            app.HdMdHhat = get_HdMdHhat(app.Hhat, app.dMdHhat);
        end
        
        function results = fit_parameters(app)
            [app.Hcr, app.mcr] = fit(app.H, app.M, cat(2, app.Hcr, app.mcr));
        end
        
        function results = update_components(app)
            app.Hcr(1) = app.TableParameters.Data(1);
            app.Hcr(2) = app.TableParameters.Data(3);
            app.mcr(1) = app.TableParameters.Data(2);
            app.mcr(2) = app.TableParameters.Data(4);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t = table([10; 0.5; 8000; 0.5; 4005], 'RowNames', {'Hcr1'; 'mcr1'; 'Hcr2'; 'mcr2'; 'Hx'});
            app.TableParameters.Data = t{:,:};
            app.TableParameters.RowName = t.Properties.RowNames;

            %t = table({' '; ' '; ' '; ' '; ' '; ' '}, 'RowNames', {'a'; 'αMs'; 'Ms'; 'α'; 'αMs/(3a)'; 'Hk'});
            %app.TableComponents.Data = t{:,:};
            %app.TableComponents.RowName = t.Properties.RowNames;

            update_components(app)
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            update_components(app)
            fit_parameters(app)
            calculate_parameters(app)
            plot(app)
        end

        % Button pushed function: CalculatePlotButton
        function CalculatePlotButtonPushed(app, event)
            update_components(app)
            calculate_parameters(app)
            plot(app)
        end

        % Value changed function: logCheckBoxM
        function logCheckBoxMValueChanged(app, event)
            update_components(app)
            calculate_parameters(app)
            
            plotter = Plotter(app.H, app.M, app.dMdH, app.HdMdH, app.Hhat, app.Mhat, app.dMdHhat, app.HdMdHhat, app.Hcr);
            cla(app.AxesM,'reset');
            if(app.logCheckBoxM.Value == 0)
                plotter.plot_M(app.AxesM);
            else
                plotter.plot_M_log(app.AxesM);
            end
        end

        % Value changed function: logCheckBoxdMdH
        function logCheckBoxdMdHValueChanged(app, event)
            update_components(app)
            calculate_parameters(app)

            plotter = Plotter(app.H, app.M, app.dMdH, app.HdMdH, app.Hhat, app.Mhat, app.dMdHhat, app.HdMdHhat, app.Hcr);
            cla(app.AxesdMdH,'reset');
            if(app.logCheckBoxdMdH.Value == 0)
                plotter.plot_dMdH(app.AxesdMdH);
            else
                plotter.plot_dMdH_log(app.AxesdMdH);
            end
        end

        % Value changed function: logCheckBoxHdMdH
        function logCheckBoxHdMdHValueChanged(app, event)
            update_components(app)
            calculate_parameters(app)
            
            plotter = Plotter(app.H, app.M, app.dMdH, app.HdMdH, app.Hhat, app.Mhat, app.dMdHhat, app.HdMdHhat, app.Hcr);
            cla(app.AxesHdMdH,'reset');
            if(app.logCheckBoxHdMdH.Value == 0)
                plotter.plot_HdMdH(app.AxesHdMdH);
            else
                plotter.plot_HdMdH_log(app.AxesHdMdH);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MagnetizationinputdataTab = uitab(app.TabGroup);
            app.MagnetizationinputdataTab.Title = 'Magnetization input data';
            app.AnhystereticmagnetizationfittingTab = uitab(app.TabGroup);
            app.AnhystereticmagnetizationfittingTab.Title = 'Anhysteretic magnetization fitting';
            app.AnhystereticmagnetizationfittingTabGridLayout = uigridlayout(app.AnhystereticmagnetizationfittingTab);
            app.GridLayoutNumbers = uigridlayout(app.AnhystereticmagnetizationfittingTabGridLayout);
            app.ComponentsLabel = uilabel(app.GridLayoutNumbers);
            app.ComponentsLabel.Text = 'Components';
            app.TableParameters = uitable(app.GridLayoutNumbers);
            app.TableParameters.ColumnName = {'Seed'};
            app.TableParameters.RowName = {};
            app.TableParameters.ColumnEditable = true;
            app.CalculatedparametersLabel = uilabel(app.GridLayoutNumbers);
            app.CalculatedparametersLabel.Text = 'Calculated parameters';
            app.TableComponents = uitable(app.GridLayoutNumbers);
            app.TableComponents.ColumnName = {'Component 1'; 'Component 2'};
            app.TableComponents.RowName = {};
            app.GridLayoutButtons = uigridlayout(app.GridLayoutNumbers);
            app.FitButton = uibutton(app.GridLayoutButtons, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.CalculatePlotButton = uibutton(app.GridLayoutButtons, 'push');
            app.CalculatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePlotButtonPushed, true);
            app.CalculatePlotButton.Text = 'Calculate & Plot';
            app.GridLayoutAxes = uigridlayout(app.AnhystereticmagnetizationfittingTabGridLayout);
            app.AxesM = uiaxes(app.GridLayoutAxes);
            xlabel(app.AxesM, 'H (A/m)')
            ylabel(app.AxesM, 'M (A/m)')
            zlabel(app.AxesM, 'Z')
            app.AxesdMdH = uiaxes(app.GridLayoutAxes);
            xlabel(app.AxesdMdH, 'H (A/m)')
            ylabel(app.AxesdMdH, '∂M/∂H')
            zlabel(app.AxesdMdH, 'Z')
            app.AxesHdMdH = uiaxes(app.GridLayoutAxes);
            xlabel(app.AxesHdMdH, 'H (A/m)')
            ylabel(app.AxesHdMdH, 'H ∂M/∂H (A/m)')
            zlabel(app.AxesHdMdH, 'Z')
            app.GridLayoutLogM = uigridlayout(app.GridLayoutAxes);
            app.logCheckBoxM = uicheckbox(app.GridLayoutLogM);
            app.logCheckBoxM.ValueChangedFcn = createCallbackFcn(app, @logCheckBoxMValueChanged, true);
            app.logCheckBoxM.Text = 'log';
            app.GridLayoutLogdMdH = uigridlayout(app.GridLayoutAxes);
            app.logCheckBoxdMdH = uicheckbox(app.GridLayoutLogdMdH);
            app.logCheckBoxdMdH.ValueChangedFcn = createCallbackFcn(app, @logCheckBoxdMdHValueChanged, true);
            app.logCheckBoxdMdH.Text = 'log';
            app.GridLayoutLogHdMdH = uigridlayout(app.GridLayoutAxes);
            app.logCheckBoxHdMdH = uicheckbox(app.GridLayoutLogHdMdH);
            app.logCheckBoxHdMdH.ValueChangedFcn = createCallbackFcn(app, @logCheckBoxHdMdHValueChanged, true);
            app.logCheckBoxHdMdH.Text = 'log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end